classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        TabGroup                  matlab.ui.container.TabGroup
        Tab                       matlab.ui.container.Tab
        AEditField                matlab.ui.control.EditField
        AEditFieldLabel           matlab.ui.control.Label
        BEditField                matlab.ui.control.EditField
        BEditFieldLabel           matlab.ui.control.Label
        EditField                 matlab.ui.control.EditField
        EditFieldLabel            matlab.ui.control.Label
        Ch_InputDropDown          matlab.ui.control.DropDown
        Ch_InputDropDownLabel     matlab.ui.control.Label
        AButton                   matlab.ui.control.Button
        BButton                   matlab.ui.control.Button
        Label                     matlab.ui.control.Label
        AverButton                matlab.ui.control.Button
        Button_19                 matlab.ui.control.Button
        MaxButton                 matlab.ui.control.Button
        MinButton                 matlab.ui.control.Button
        CButton                   matlab.ui.control.Button
        Button_18                 matlab.ui.control.Button
        Button_17                 matlab.ui.control.Button
        Button_16                 matlab.ui.control.Button
        Button_15                 matlab.ui.control.Button
        Button_14                 matlab.ui.control.Button
        Button_13                 matlab.ui.control.Button
        Button_12                 matlab.ui.control.Button
        Button_11                 matlab.ui.control.Button
        Button_10                 matlab.ui.control.Button
        Button_9                  matlab.ui.control.Button
        Button_8                  matlab.ui.control.Button
        Button_7                  matlab.ui.control.Button
        Button_6                  matlab.ui.control.Button
        Button_5                  matlab.ui.control.Button
        Button_4                  matlab.ui.control.Button
        Button_3                  matlab.ui.control.Button
        Button_2                  matlab.ui.control.Button
        Button                    matlab.ui.control.Button
        Tab_2                     matlab.ui.container.Tab
        TabGroup2                 matlab.ui.container.TabGroup
        D3DTab                    matlab.ui.container.Tab
        Lamp                      matlab.ui.control.Lamp
        yEditField                matlab.ui.control.EditField
        yEditFieldLabel           matlab.ui.control.Label
        Button_21                 matlab.ui.control.Button
        ColorButtonGroup          matlab.ui.container.ButtonGroup
        RedButton                 matlab.ui.control.RadioButton
        noneButton                matlab.ui.control.RadioButton
        GreenButton               matlab.ui.control.RadioButton
        BlueButton                matlab.ui.control.RadioButton
        Switch                    matlab.ui.control.Switch
        Label_4                   matlab.ui.control.Label
        RunSwitch                 matlab.ui.control.Switch
        RunSwitchLabel            matlab.ui.control.Label
        LineKnob                  matlab.ui.control.DiscreteKnob
        LineKnobLabel             matlab.ui.control.Label
        ListBox                   matlab.ui.control.ListBox
        Label_2                   matlab.ui.control.Label
        DTab_2                    matlab.ui.container.Tab
        Button_22                 matlab.ui.control.Button
        ColorKnob                 matlab.ui.control.DiscreteKnob
        ColorKnobLabel            matlab.ui.control.Label
        yEditField_2              matlab.ui.control.EditField
        yEditField_2Label         matlab.ui.control.Label
        ListBox_2                 matlab.ui.control.ListBox
        Label_3                   matlab.ui.control.Label
        increaseEditField         matlab.ui.control.EditField
        increaseEditFieldLabel    matlab.ui.control.Label
        x_endEditField            matlab.ui.control.EditField
        x_endEditFieldLabel       matlab.ui.control.Label
        x_startEditField          matlab.ui.control.EditField
        x_startEditFieldLabel     matlab.ui.control.Label
        fxEditField               matlab.ui.control.EditField
        fxEditFieldLabel          matlab.ui.control.Label
        OutputButton              matlab.ui.control.Button
        UIAxes                    matlab.ui.control.UIAxes
        SpecialTab                matlab.ui.container.Tab
        outputButton              matlab.ui.control.Button
        TabGroup3                 matlab.ui.container.TabGroup
        BarTab                    matlab.ui.container.Tab
        Image                     matlab.ui.control.Image
        fxEditField_2             matlab.ui.control.EditField
        fxEditField_2Label        matlab.ui.control.Label
        increaseEditField_2       matlab.ui.control.EditField
        increaseEditField_2Label  matlab.ui.control.Label
        x_endEditField_2          matlab.ui.control.EditField
        x_endEditField_2Label     matlab.ui.control.Label
        x_startEditField_2        matlab.ui.control.EditField
        x_startEditField_2Label   matlab.ui.control.Label
        PieTab                    matlab.ui.container.Tab
        impEditField              matlab.ui.control.EditField
        impEditFieldLabel         matlab.ui.control.Label
        labelEditField            matlab.ui.control.EditField
        labelEditFieldLabel       matlab.ui.control.Label
        xEditField                matlab.ui.control.EditField
        xEditFieldLabel           matlab.ui.control.Label
        PolarTab                  matlab.ui.container.Tab
        Image2                    matlab.ui.control.Image
        rEditField                matlab.ui.control.EditField
        rEditFieldLabel           matlab.ui.control.Label
        increaseEditField_3       matlab.ui.control.EditField
        increaseEditField_3Label  matlab.ui.control.Label
        s_endEditField            matlab.ui.control.EditField
        s_endEditFieldLabel       matlab.ui.control.Label
        s_startEditField          matlab.ui.control.EditField
        s_startEditFieldLabel     matlab.ui.control.Label
        StemTab                   matlab.ui.container.Tab
        fxEditField_3             matlab.ui.control.EditField
        fxEditField_3Label        matlab.ui.control.Label
        x_endEditField_3          matlab.ui.control.EditField
        x_endEditField_3Label     matlab.ui.control.Label
        increaseEditField_4       matlab.ui.control.EditField
        increaseEditField_4Label  matlab.ui.control.Label
        x_startEditField_3        matlab.ui.control.EditField
        x_startEditField_3Label   matlab.ui.control.Label
        MarkerEdgeColorKnob       matlab.ui.control.DiscreteKnob
        MarkerEdgeColorKnobLabel  matlab.ui.control.Label
        MarkerFaceColorKnob       matlab.ui.control.DiscreteKnob
        MarkerFaceColorKnobLabel  matlab.ui.control.Label
        LineStyleKnob             matlab.ui.control.DiscreteKnob
        LineStyleKnobLabel        matlab.ui.control.Label
        ErrorbarTab               matlab.ui.container.Tab
        EditField_3               matlab.ui.control.EditField
        Label_5                   matlab.ui.control.Label
        yEditField_3              matlab.ui.control.EditField
        yEditField_3Label         matlab.ui.control.Label
        increaseEditField_5       matlab.ui.control.EditField
        increaseEditField_5Label  matlab.ui.control.Label
        x_endEditField_4          matlab.ui.control.EditField
        x_endEditField_4Label     matlab.ui.control.Label
        x_startEditField_4        matlab.ui.control.EditField
        x_startEditField_4Label   matlab.ui.control.Label
        UIAxes2                   matlab.ui.control.UIAxes
        Tab_3                     matlab.ui.container.Tab
        GridLayout                matlab.ui.container.GridLayout
        Label_6                   matlab.ui.control.Label
        EditField_5               matlab.ui.control.EditField
        Button_46                 matlab.ui.control.Button
        EditField_4               matlab.ui.control.EditField
        log2Button                matlab.ui.control.Button
        log10Button               matlab.ui.control.Button
        lnButton                  matlab.ui.control.Button
        Button_45                 matlab.ui.control.Button
        sqrtButton                matlab.ui.control.Button
        powButton                 matlab.ui.control.Button
        eButton                   matlab.ui.control.Button
        tanButton                 matlab.ui.control.Button
        cosButton                 matlab.ui.control.Button
        sinButton                 matlab.ui.control.Button
        Button_34                 matlab.ui.control.Button
        Button_32                 matlab.ui.control.Button
        Button_33                 matlab.ui.control.Button
        Button_31                 matlab.ui.control.Button
        Button_30                 matlab.ui.control.Button
        Button_29                 matlab.ui.control.Button
        Button_38                 matlab.ui.control.Button
        Button_41                 matlab.ui.control.Button
        Button_39                 matlab.ui.control.Button
        Button_28                 matlab.ui.control.Button
        Button_27                 matlab.ui.control.Button
        XButton                   matlab.ui.control.Button
        Button_26                 matlab.ui.control.Button
        Button_25                 matlab.ui.control.Button
        Button_40                 matlab.ui.control.Button
        Button_44                 matlab.ui.control.Button
        Button_37                 matlab.ui.control.Button
        Button_24                 matlab.ui.control.Button
        Button_36                 matlab.ui.control.Button
        Button_43                 matlab.ui.control.Button
        Button_23                 matlab.ui.control.Button
        EditField_2               matlab.ui.control.EditField
        Button_35                 matlab.ui.control.Button
        CButton_2                 matlab.ui.control.Button
        Button_42                 matlab.ui.control.Button
        Tab_4                     matlab.ui.container.Tab
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller, sz, c)
            
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            tab=app.Ch_InputDropDown.Value;
            switch tab
                case 'A'
                    app.AEditField.Value=append(app.AEditField.Value,'1');
                case 'B'
                    app.BEditField.Value=append(app.BEditField.Value,'1');
            end
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            tab=app.Ch_InputDropDown.Value;
            switch tab
                case 'A'
                    app.AEditField.Value=append(app.AEditField.Value,'2');
                case 'B'
                    app.BEditField.Value=append(app.BEditField.Value,'2');
            end
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            tab=app.Ch_InputDropDown.Value;
            switch tab
                case 'A'
                    app.AEditField.Value=append(app.AEditField.Value,'3');
                case 'B'
                    app.BEditField.Value=append(app.BEditField.Value,'3');
            end
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            tab=app.Ch_InputDropDown.Value;
            switch tab
                case 'A'
                    app.AEditField.Value=append(app.AEditField.Value,'4');
                case 'B'
                    app.BEditField.Value=append(app.BEditField.Value,'4');
            end
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
            tab=app.Ch_InputDropDown.Value;
            switch tab
                case 'A'
                    app.AEditField.Value=append(app.AEditField.Value,'5');
                case 'B'
                    app.BEditField.Value=append(app.BEditField.Value,'5');
            end
        end

        % Button pushed function: Button_6
        function Button_6Pushed(app, event)
            tab=app.Ch_InputDropDown.Value;
            switch tab
                case 'A'
                    app.AEditField.Value=append(app.AEditField.Value,'6');
                case 'B'
                    app.BEditField.Value=append(app.BEditField.Value,'6');
            end
        end

        % Button pushed function: Button_7
        function Button_7Pushed(app, event)
            tab=app.Ch_InputDropDown.Value;
            switch tab
                case 'A'
                    app.AEditField.Value=append(app.AEditField.Value,'7');
                case 'B'
                    app.BEditField.Value=append(app.BEditField.Value,'7');
            end
        end

        % Button pushed function: Button_8
        function Button_8Pushed(app, event)
            tab=app.Ch_InputDropDown.Value;
            switch tab
                case 'A'
                    app.AEditField.Value=append(app.AEditField.Value,'8');
                case 'B'
                    app.BEditField.Value=append(app.BEditField.Value,'8');
            end
        end

        % Button pushed function: Button_9
        function Button_9Pushed(app, event)
            tab=app.Ch_InputDropDown.Value;
            switch tab
                case 'A'
                    app.AEditField.Value=append(app.AEditField.Value,'9');
                case 'B'
                    app.BEditField.Value=append(app.BEditField.Value,'9');
            end
        end

        % Button pushed function: Button_11
        function Button_11Pushed(app, event)
            try
            a=eval(app.AEditField.Value);
            b=eval(app.BEditField.Value);
            app.Label.Text='+';
            app.EditField.Value=num2str(a+b);
            end
        end

        % Button pushed function: Button_10
        function Button_10Pushed(app, event)
            tab=app.Ch_InputDropDown.Value;
            switch tab
                case 'A'
                    app.AEditField.Value=append(app.AEditField.Value,'0');
                case 'B'
                    app.BEditField.Value=append(app.BEditField.Value,'0');
            end
        end

        % Button pushed function: Button_12
        function Button_12Pushed(app, event)
            try
            a=eval(app.AEditField.Value);
            b=eval(app.BEditField.Value);
            app.EditField.Value=num2str(a-b);
            app.Label.Text='-';
            end
        end

        % Button pushed function: Button_13
        function Button_13Pushed(app, event)
            try
            a=eval(app.AEditField.Value);
            b=eval(app.BEditField.Value);
            app.EditField.Value=num2str(mod(a,b));
            app.Label.Text='%';
            end
        end

        % Button pushed function: Button_14
        function Button_14Pushed(app, event)
            try
            a=eval(app.AEditField.Value);
            b=eval(app.BEditField.Value);
            app.EditField.Value=num2str(a*b);
            app.Label.Text='*';
            end
        end

        % Button pushed function: Button_15
        function Button_15Pushed(app, event)
            try
            a=eval(app.AEditField.Value);
            b=eval(app.BEditField.Value);
            app.EditField.Value=num2str(a/b);
            app.Label.Text='÷';
            end
        end

        % Button pushed function: Button_16
        function Button_16Pushed(app, event)
            
            tab=app.Ch_InputDropDown.Value;
            switch tab
                case 'A'
                    app.AEditField.Value=append(app.AEditField.Value,'[');
                case 'B'
                    app.BEditField.Value=append(app.BEditField.Value,'[');
            end
           
        end

        % Button pushed function: Button_17
        function Button_17Pushed(app, event)
            tab=app.Ch_InputDropDown.Value;
            switch tab
                case 'A'
                    app.AEditField.Value=append(app.AEditField.Value,']');
                case 'B'
                    app.BEditField.Value=append(app.BEditField.Value,']');
            end
        end

        % Button pushed function: Button_18
        function Button_18Pushed(app, event)
            tab=app.Ch_InputDropDown.Value;
            switch tab
                case 'A'
                    app.AEditField.Value=append(app.AEditField.Value,';');
                case 'B'
                    app.BEditField.Value=append(app.BEditField.Value,';');
            end
        end

        % Button pushed function: CButton
        function CButtonPushed(app, event)
            tab=app.Ch_InputDropDown.Value;
            switch tab
                case 'A'
                    app.AEditField.Value='';
                case 'B'
                    app.BEditField.Value='';
            end
        end

        % Button pushed function: MinButton
        function MinButtonPushed(app, event)
            try
            tab=app.Ch_InputDropDown.Value;
            a=eval(app.AEditField.Value);
            b=eval(app.BEditField.Value);
            switch tab
                case 'A'
                    app.AEditField.Value=num2str(min(a));
                case 'B'
                    app.BEditField.Value=num2str(min(b));
                case '結果(Min&Max)'
                    app.EditField.Value=num2str(min(a,b));
                    if a>b
                        app.Label.Text='>';
                    elseif a<b
                        app.Label.Text='<';
                    elseif a==b
                        app.Label.Text='=';
                    end 
                
                    
            end
            end
        end

        % Button pushed function: MaxButton
        function MaxButtonPushed(app, event)
            try
            tab=app.Ch_InputDropDown.Value;
            a=eval(app.AEditField.Value);
            b=eval(app.BEditField.Value);
            switch tab
                case 'A'
                    app.AEditField.Value=num2str(max(a));
                case 'B'
                    app.BEditField.Value=num2str(max(b));
                case '結果(Min&Max)'
                    app.EditField.Value=num2str(max(a,b));
                    if a>b
                        app.Label.Text='>';
                    elseif a<b
                        app.Label.Text='<';
                    elseif a==b
                        app.Label.Text='=';
                    end 
            end
            end
        end

        % Button pushed function: Button_19
        function Button_19Pushed(app, event)
            tab=app.Ch_InputDropDown.Value;
            switch tab
                case 'A'
                    app.AEditField.Value=append(app.AEditField.Value,',');
                case 'B'
                    app.BEditField.Value=append(app.BEditField.Value,',');
            end
        end

        % Button pushed function: AverButton
        function AverButtonPushed(app, event)
            try
            a=eval(app.AEditField.Value);
            b=eval(app.BEditField.Value);
            app.EditField.Value=num2str((a+b)/2);
            app.Label.Text='μ';
            end
        end

        % Button pushed function: AButton
        function AButtonPushed(app, event)
            app.Ch_InputDropDown.Value='A';
        end

        % Button pushed function: BButton
        function BButtonPushed(app, event)
            app.Ch_InputDropDown.Value='B';
        end

        % Button pushed function: OutputButton
        function OutputButtonPushed(app, event)
        try
       cla(app.UIAxes,"reset");
        x=eval(app.x_startEditField.Value):eval(app.increaseEditField.Value):eval(app.x_endEditField.Value);
        
        axis(app.UIAxes,[-inf inf -10 100]); 
        k=x;
        switch app.TabGroup2.SelectedTab.Title
            case '2D 3D'
                if app.RedButton.Value==1
                    c1='r';
                elseif app.BlueButton.Value==1
                    c1='b';
                elseif app.GreenButton.Value==1
                    c1='g';
                elseif app.noneButton.Value==1
                    c1='k';
                end
                switch app.LineKnob.Value
                    case '實線'
                        c2='-';
                    case '虛線'
                        c2='--';
                    case '點線'
                        c2='.';
                    case '點虛線'
                        c2='-.';    
                end
                style=append(c1,c2);

                if app.Switch.Value=='2D'
                    
                    if app.RunSwitch.Value=="On"
                        app.Lamp.Color=[1,1,1];
                        for i = 1:10
                            x=x-(1/20);
                            y = eval(app.fxEditField.Value);
                            plot(app.UIAxes,k, y,style);
                            pause(0.000001);
                        end
                        app.Lamp.Color=[0,1,0];
                    else 
                        plot(app.UIAxes,x,eval(app.fxEditField.Value),style);
                        
                    end
                else
                    y=x;
                    [X, Y] = meshgrid(x, y);
                    z = X./exp((X-Y.^2).^2+Y.^2);
                    plot3(app.UIAxes,X,Y,z,style);

                end
            case  '3D'
                 x = eval(app.x_startEditField.Value):eval(app.increaseEditField.Value):eval(app.x_endEditField.Value); 
                 y = eval(app.yEditField_2.Value); 
                 [xx, yy] = meshgrid(x, y);
                 zz = eval(app.fxEditField.Value);
                 mesh(app.UIAxes,zz); 
                 switch app.ColorKnob.Value
                       case 'Red'
                         colormap(app.UIAxes,[1 0 0]);
                       case 'Blue'
                         colormap(app.UIAxes,[0 0 1]);
                       case 'Green'
                         colormap(app.UIAxes,[0 1 0]);
                       case 'Black'
                         colormap(app.UIAxes,[1 1 1]);
                 end


        end    
        
        end
        end

        % Callback function
        function Button_21Pushed(app, event)
      
        end

        % Button pushed function: outputButton
        function outputButtonPushed(app, event)
            try
            cla(app.UIAxes2,"reset");
            switch app.TabGroup3.SelectedTab.Title
                case 'Bar'
                    x=eval(app.x_startEditField_2.Value):eval(app.increaseEditField_2.Value):eval(app.x_endEditField_2.Value);
                    y=eval(app.fxEditField_2.Value);
                    bar(app.UIAxes2,x,y);
                    
                case 'Pie'
                    x =eval(append(append('[',app.xEditField.Value),']'));
                    label=eval(append(append('{',app.labelEditField.Value),'}'));
                    imp=eval(append(append('[',app.impEditField.Value),']'));
                    pie(app.UIAxes2,x,imp,label);

                case 'Polar'
                    s=eval(app.s_startEditField.Value):eval(app.increaseEditField_3.Value):eval(app.s_endEditField.Value);
                    r=eval(app.rEditField.Value);
                    h = polar(app.UIAxes2,s,r);
                case 'Stem'
                    x=eval(app.x_startEditField_3.Value):eval(app.increaseEditField_4.Value):eval(app.x_endEditField_3.Value);
                    switch app.LineStyleKnob.Value
                        case '實線'
                            ls='-';
                        case '虛線'
                            ls='--';
                        case '點線'
                            ls=':';
                        case '點虛線'
                            ls='-.';   
                        case 'Off'
                            ls='none';
                    end    
                    stem(app.UIAxes2,x,eval(app.fxEditField_3.Value),'filled','LineStyle',ls,...
                                                             'MarkerFaceColor',app.MarkerFaceColorKnob.Value,...
                                                             'MarkerEdgeColor',app.MarkerEdgeColorKnob.Value);

                case 'Errorbar' 
                    x=eval(app.x_startEditField_4.Value):eval(app.increaseEditField_5.Value):eval(app.x_endEditField_4.Value);
                    y=eval(app.yEditField_3.Value);
                    err = eval(app.EditField_3.Value);
                    errorbar(app.UIAxes2,x,y,err)
            end
            end
        end

        % Callback function
        function sizeKnobValueChanged(app, event)
        if app.RedButton.Value==1
            c1='r';
        elseif app.BlueButton.Value==1
            c1='b';
        elseif app.GreenButton.Value==1
            c1='g';
        elseif app.noneButton.Value==1
            c1='k';
        end
        switch app.LineKnob.Value
            case '實線'
                c2='-';
            case '虛線'
                c2='--';
            case '點線'
                c2='.';
            case '點虛線'
                c2='-.';    
        end
        style=append(c1,c2);
       cla(app.UIAxes,"reset");
        x=eval(app.x_startEditField.Value):eval(app.increaseEditField.Value):eval(app.x_endEditField.Value);
        
        axis(app.UIAxes,[-inf inf -10 100]); 
        k=x;
        switch app.TabGroup2.SelectedTab.Title
            case '2D 3D'

                if app.Switch.Value=='2D'
                    if app.RunSwitch.Value=="On"
                        for i = 1:1000
                            x=x-(1/20);
                            y = eval(app.fxEditField.Value);
                            if app.RunSwitch.Value=="Off"
                                break;
                            end
                            plot(app.UIAxes,k, y,style);
                            pause(0.00001) 
                        end
                    else 
                        plot(app.UIAxes,x,eval(app.fxEditField.Value)+app.sizeKnob.Value,style);
                        
                    end
                else
                    y=eval(app.yEditField.Value);
                    [X, Y] = meshgrid(x, y);
                    z = eval(app.fxEditField.Value);
                    plot3(app.UIAxes,X,Y,z,style);

                end
            
                
        end    
        
            
        end

        % Value changed function: ListBox
        function ListBoxValueChanged(app, event)
            value = app.ListBox.Value;
            
        end

        % Callback function
        function Button_21Pushed2(app, event)
            
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            app.UIAxes.Renderer = 'painters';
        end

        % Button pushed function: Button_21
        function Button_21Pushed3(app, event)
            switch app.ListBox.Value
                case '2D_1'
                    app.x_startEditField.Value='0';
                    app.x_endEditField.Value='5';
                    app.increaseEditField.Value='0.01';
                    app.fxEditField.Value='2*sin(2*pi*x +pi/4)';
                    
                case '2D_2'
                    app.x_startEditField.Value='0';
                    app.x_endEditField.Value='5';
                    app.increaseEditField.Value='0.01';
                    app.fxEditField.Value='sin(2*x)./x';
                case '3D_1'
                    app.x_startEditField.Value='-1';
                    app.x_endEditField.Value='1';
                    app.yEditField.Value='0.1.*x.*cos(x)';
                    app.increaseEditField.Value='0.1';
                    app.fxEditField.Value='X./exp((X-Y.^2).^2+Y.^2)';
                case '3D_2'
                    app.x_startEditField.Value='0';
                    app.x_endEditField.Value='24*pi';
                    app.yEditField.Value='x';
                    app.increaseEditField.Value='0.1';
                    app.fxEditField.Value='X./exp((X-Y.^2).^2+Y.^2)';
            end
        end

        % Button pushed function: Button_22
        function Button_22Pushed(app, event)
            switch app.ListBox_2.Value
                case 'F1'
                    app.x_startEditField.Value='-5';
                    app.x_endEditField.Value='5';
                    app.increaseEditField.Value='1';
                    app.yEditField_2.Value='x';
                    app.fxEditField.Value='xx.*exp(xx.^2+yy.^2)';
                case 'F2'    
                    app.x_startEditField.Value='-1';
                    app.x_endEditField.Value='1';
                    app.increaseEditField.Value='0.1'
                    app.yEditField_2.Value='x';
                    app.fxEditField.Value=' xx./exp((xx-yy.^2).^2+y.^2)';
            end
        end

        % Value changed function: RunSwitch
        function RunSwitchValueChanged(app, event)
            value = app.RunSwitch.Value;
            disp(value)
        end

        % Button pushed function: Button_39
        function Button_39Pushed(app, event)
            app.EditField_2.Value=append(app.EditField_2.Value,'1');
        end

        % Button pushed function: Button_35
        function Button_35Pushed(app, event)
            app.EditField_2.Value=append(app.EditField_2.Value,'7');
        end

        % Button pushed function: Button_36
        function Button_36Pushed(app, event)
            app.EditField_2.Value=append(app.EditField_2.Value,'8');
        end

        % Button pushed function: Button_37
        function Button_37Pushed(app, event)
            app.EditField_2.Value=append(app.EditField_2.Value,'9');
        end

        % Button pushed function: Button_42
        function Button_42Pushed(app, event)
            app.EditField_2.Value=append(app.EditField_2.Value,'4');
        end

        % Button pushed function: Button_43
        function Button_43Pushed(app, event)
            app.EditField_2.Value=append(app.EditField_2.Value,'5');
        end

        % Button pushed function: Button_44
        function Button_44Pushed(app, event)
            app.EditField_2.Value=append(app.EditField_2.Value,'6');
        end

        % Button pushed function: Button_40
        function Button_40Pushed(app, event)
            app.EditField_2.Value=append(app.EditField_2.Value,'2');
        end

        % Button pushed function: Button_41
        function Button_41Pushed(app, event)
            app.EditField_2.Value=append(app.EditField_2.Value,'3');
        end

        % Button pushed function: Button_38
        function Button_38Pushed(app, event)
            app.EditField_2.Value=append(app.EditField_2.Value,'0');
        end

        % Button pushed function: CButton_2
        function CButton_2Pushed(app, event)
            app.EditField_2.Value='';
        end

        % Button pushed function: Button_23
        function Button_23Pushed(app, event)
            app.EditField_2.Value=append(app.EditField_2.Value,'(');
        end

        % Button pushed function: Button_24
        function Button_24Pushed(app, event)
            app.EditField_2.Value=append(app.EditField_2.Value,')');
        end

        % Button pushed function: Button_30
        function Button_30Pushed(app, event)
            app.EditField_2.Value=append(app.EditField_2.Value,'[');
        end

        % Button pushed function: Button_31
        function Button_31Pushed(app, event)
            app.EditField_2.Value=append(app.EditField_2.Value,']');
        end

        % Button pushed function: Button_25
        function Button_25Pushed(app, event)
            app.EditField_2.Value=append(app.EditField_2.Value,'mod(');
            app.EditField_5.Value='mod(a,b)';
        end

        % Button pushed function: Button_33
        function Button_33Pushed(app, event)
            app.EditField_2.Value=append(app.EditField_2.Value,',');
        end

        % Button pushed function: Button_32
        function Button_32Pushed(app, event)
            app.EditField_2.Value=append(app.EditField_2.Value,';');
        end

        % Button pushed function: Button_26
        function Button_26Pushed(app, event)
            app.EditField_2.Value=append(app.EditField_2.Value,'/');
        end

        % Button pushed function: Button_34
        function Button_34Pushed(app, event)
            app.EditField_2.Value=append(app.EditField_2.Value,'.');
        end

        % Button pushed function: sinButton
        function sinButtonPushed(app, event)
            app.EditField_2.Value=append(app.EditField_2.Value,'sin(');
            app.EditField_5.Value='sin(a*pi)';
        end

        % Button pushed function: XButton
        function XButtonPushed(app, event)
            app.EditField_2.Value=append(app.EditField_2.Value,'*');
        end

        % Button pushed function: sqrtButton
        function sqrtButtonPushed(app, event)
            app.EditField_2.Value=append(app.EditField_2.Value,'sqrt(');
            app.EditField_5.Value='sqrt(a)';
        end

        % Button pushed function: cosButton
        function cosButtonPushed(app, event)
            app.EditField_2.Value=append(app.EditField_2.Value,'cos(');
            app.EditField_5.Value='cos(a*pi)';
        end

        % Button pushed function: Button_27
        function Button_27Pushed(app, event)
            app.EditField_2.Value=append(app.EditField_2.Value,'-');
        end

        % Button pushed function: Button_28
        function Button_28Pushed(app, event)
            app.EditField_2.Value=append(app.EditField_2.Value,'+');
        end

        % Button pushed function: tanButton
        function tanButtonPushed(app, event)
            app.EditField_2.Value=append(app.EditField_2.Value,'tan(');
            app.EditField_5.Value='tan(a*pi)';
        end

        % Button pushed function: powButton
        function powButtonPushed(app, event)
            app.EditField_2.Value=append(app.EditField_2.Value,'^');
        end

        % Button pushed function: log2Button
        function log2ButtonPushed(app, event)
            app.EditField_2.Value=append(app.EditField_2.Value,'log2(');
            app.EditField_5.Value='log2(a)';
        end

        % Button pushed function: log10Button
        function log10ButtonPushed(app, event)
            app.EditField_2.Value=append(app.EditField_2.Value,'log10(');
            app.EditField_5.Value='log10(a)';
        end

        % Button pushed function: lnButton
        function lnButtonPushed(app, event)
            app.EditField_2.Value=append(app.EditField_2.Value,'log(');
            app.EditField_5.Value='log(a)';
        end

        % Button pushed function: eButton
        function eButtonPushed(app, event)
            app.EditField_2.Value=append(app.EditField_2.Value,'exp(');
            app.EditField_5.Value='exp(a)';
        end

        % Button pushed function: Button_45
        function Button_45Pushed(app, event)
            a=app.EditField_2.Value;
            try
                a(length(a))='';
            end
            app.EditField_2.Value=a;
        end

        % Button pushed function: Button_29
        function Button_29Pushed(app, event)
            try
               app.EditField_4.Value=num2str(eval(app.EditField_2.Value));
            catch
               app.EditField_4.Value='ERROR';
            end
        end

        % Button pushed function: Button_46
        function Button_46Pushed(app, event)
            app.EditField_2.Value=append(app.EditField_2.Value,'pi');
        end

        % Callback function
        function Button_47Pushed(app, event)
            delete(app);
        end

        % Size changed function: Tab_4
        function Tab_4SizeChanged(app, event)
            position = app.Tab_4.Position;
            
        end

        % Button down function: Tab_4
        function Tab_4ButtonDown(app, event)
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = '計算機';
            app.Button = uibutton(app.Tab, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '1';
            app.Button_2 = uibutton(app.Tab, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '2';
            app.Button_3 = uibutton(app.Tab, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '3';
            app.Button_4 = uibutton(app.Tab, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '4';
            app.Button_5 = uibutton(app.Tab, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Text = '5';
            app.Button_6 = uibutton(app.Tab, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_6.Text = '6';
            app.Button_7 = uibutton(app.Tab, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @Button_7Pushed, true);
            app.Button_7.Text = '7';
            app.Button_8 = uibutton(app.Tab, 'push');
            app.Button_8.ButtonPushedFcn = createCallbackFcn(app, @Button_8Pushed, true);
            app.Button_8.Text = '8';
            app.Button_9 = uibutton(app.Tab, 'push');
            app.Button_9.ButtonPushedFcn = createCallbackFcn(app, @Button_9Pushed, true);
            app.Button_9.Text = '9';
            app.Button_10 = uibutton(app.Tab, 'push');
            app.Button_10.ButtonPushedFcn = createCallbackFcn(app, @Button_10Pushed, true);
            app.Button_10.Text = '0';
            app.Button_11 = uibutton(app.Tab, 'push');
            app.Button_11.ButtonPushedFcn = createCallbackFcn(app, @Button_11Pushed, true);
            app.Button_11.Text = '+';
            app.Button_12 = uibutton(app.Tab, 'push');
            app.Button_12.ButtonPushedFcn = createCallbackFcn(app, @Button_12Pushed, true);
            app.Button_12.Text = '-';
            app.Button_13 = uibutton(app.Tab, 'push');
            app.Button_13.ButtonPushedFcn = createCallbackFcn(app, @Button_13Pushed, true);
            app.Button_13.Text = '%';
            app.Button_14 = uibutton(app.Tab, 'push');
            app.Button_14.ButtonPushedFcn = createCallbackFcn(app, @Button_14Pushed, true);
            app.Button_14.Text = '*';
            app.Button_15 = uibutton(app.Tab, 'push');
            app.Button_15.ButtonPushedFcn = createCallbackFcn(app, @Button_15Pushed, true);
            app.Button_15.Text = '/';
            app.Button_16 = uibutton(app.Tab, 'push');
            app.Button_16.ButtonPushedFcn = createCallbackFcn(app, @Button_16Pushed, true);
            app.Button_16.Text = '[';
            app.Button_17 = uibutton(app.Tab, 'push');
            app.Button_17.ButtonPushedFcn = createCallbackFcn(app, @Button_17Pushed, true);
            app.Button_17.Text = ']';
            app.Button_18 = uibutton(app.Tab, 'push');
            app.Button_18.ButtonPushedFcn = createCallbackFcn(app, @Button_18Pushed, true);
            app.Button_18.Text = ';';
            app.CButton = uibutton(app.Tab, 'push');
            app.CButton.ButtonPushedFcn = createCallbackFcn(app, @CButtonPushed, true);
            app.CButton.Text = 'C';
            app.MinButton = uibutton(app.Tab, 'push');
            app.MinButton.ButtonPushedFcn = createCallbackFcn(app, @MinButtonPushed, true);
            app.MinButton.Text = 'Min';
            app.MaxButton = uibutton(app.Tab, 'push');
            app.MaxButton.ButtonPushedFcn = createCallbackFcn(app, @MaxButtonPushed, true);
            app.MaxButton.Text = 'Max';
            app.Button_19 = uibutton(app.Tab, 'push');
            app.Button_19.ButtonPushedFcn = createCallbackFcn(app, @Button_19Pushed, true);
            app.Button_19.Text = ',';
            app.AverButton = uibutton(app.Tab, 'push');
            app.AverButton.ButtonPushedFcn = createCallbackFcn(app, @AverButtonPushed, true);
            app.AverButton.Text = 'Aver';
            app.Label = uilabel(app.Tab);
            app.Label.Text = '?';
            app.BButton = uibutton(app.Tab, 'push');
            app.BButton.ButtonPushedFcn = createCallbackFcn(app, @BButtonPushed, true);
            app.BButton.Text = 'B';
            app.AButton = uibutton(app.Tab, 'push');
            app.AButton.ButtonPushedFcn = createCallbackFcn(app, @AButtonPushed, true);
            app.AButton.Text = 'A';
            app.Ch_InputDropDownLabel = uilabel(app.Tab);
            app.Ch_InputDropDownLabel.Text = 'Ch_Input';
            app.Ch_InputDropDown = uidropdown(app.Tab);
            app.Ch_InputDropDown.Items = {'A', 'B', '結果(Min&Max)'};
            app.Ch_InputDropDown.Value = 'A';
            app.EditFieldLabel = uilabel(app.Tab);
            app.EditFieldLabel.Text = '結果';
            app.EditField = uieditfield(app.Tab, 'text');
            app.BEditFieldLabel = uilabel(app.Tab);
            app.BEditFieldLabel.Text = 'B';
            app.BEditField = uieditfield(app.Tab, 'text');
            app.AEditFieldLabel = uilabel(app.Tab);
            app.AEditFieldLabel.Text = 'A';
            app.AEditField = uieditfield(app.Tab, 'text');
            app.Tab_2 = uitab(app.TabGroup);
            app.Tab_2.Title = '動畫';
            app.UIAxes = uiaxes(app.Tab_2);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            colormap(app.UIAxes, 'spring')
            app.OutputButton = uibutton(app.Tab_2, 'push');
            app.OutputButton.ButtonPushedFcn = createCallbackFcn(app, @OutputButtonPushed, true);
            app.OutputButton.Text = 'Output';
            app.fxEditFieldLabel = uilabel(app.Tab_2);
            app.fxEditFieldLabel.Text = 'f(x)';
            app.fxEditField = uieditfield(app.Tab_2, 'text');
            app.fxEditField.Value = '2.*exp(sin(x)).*sin(4.*x)';
            app.x_startEditFieldLabel = uilabel(app.Tab_2);
            app.x_startEditFieldLabel.Text = 'x_start';
            app.x_startEditField = uieditfield(app.Tab_2, 'text');
            app.x_startEditField.Value = '0';
            app.x_endEditFieldLabel = uilabel(app.Tab_2);
            app.x_endEditFieldLabel.Text = 'x_end';
            app.x_endEditField = uieditfield(app.Tab_2, 'text');
            app.x_endEditField.Value = '4*pi';
            app.increaseEditFieldLabel = uilabel(app.Tab_2);
            app.increaseEditFieldLabel.Text = 'increase';
            app.increaseEditField = uieditfield(app.Tab_2, 'text');
            app.increaseEditField.Value = '0.1';
            app.TabGroup2 = uitabgroup(app.Tab_2);
            app.D3DTab = uitab(app.TabGroup2);
            app.D3DTab.Title = '2D 3D';
            app.Label_2 = uilabel(app.D3DTab);
            app.Label_2.Text = '範例';
            app.ListBox = uilistbox(app.D3DTab);
            app.ListBox.Items = {'2D_1', '2D_2', '3D_1', '3D_2'};
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
            app.ListBox.Value = '2D_1';
            app.LineKnobLabel = uilabel(app.D3DTab);
            app.LineKnobLabel.Text = 'Line';
            app.LineKnob = uiknob(app.D3DTab, 'discrete');
            app.LineKnob.Items = {'實線', '虛線', '點線', '點虛線'};
            app.LineKnob.Value = '實線';
            app.RunSwitchLabel = uilabel(app.D3DTab);
            app.RunSwitchLabel.Text = 'Run';
            app.RunSwitch = uiswitch(app.D3DTab, 'slider');
            app.RunSwitch.ValueChangedFcn = createCallbackFcn(app, @RunSwitchValueChanged, true);
            app.Label_4 = uilabel(app.D3DTab);
            app.Label_4.Text = '圖形';
            app.Switch = uiswitch(app.D3DTab, 'slider');
            app.Switch.Items = {'2D', '3D'};
            app.Switch.Value = '2D';
            app.ColorButtonGroup = uibuttongroup(app.D3DTab);
            app.ColorButtonGroup.Title = 'Color';
            app.BlueButton = uiradiobutton(app.ColorButtonGroup);
            app.BlueButton.Text = 'Blue';
            app.GreenButton = uiradiobutton(app.ColorButtonGroup);
            app.GreenButton.Text = 'Green';
            app.noneButton = uiradiobutton(app.ColorButtonGroup);
            app.noneButton.Text = 'none';
            app.noneButton.Value = true;
            app.RedButton = uiradiobutton(app.ColorButtonGroup);
            app.RedButton.Text = 'Red';
            app.Button_21 = uibutton(app.D3DTab, 'push');
            app.Button_21.ButtonPushedFcn = createCallbackFcn(app, @Button_21Pushed3, true);
            app.Button_21.Text = '範例生成';
            app.yEditFieldLabel = uilabel(app.D3DTab);
            app.yEditFieldLabel.Text = 'y';
            app.yEditField = uieditfield(app.D3DTab, 'text');
            app.Lamp = uilamp(app.D3DTab);
            app.DTab_2 = uitab(app.TabGroup2);
            app.DTab_2.Title = '3D';
            app.Label_3 = uilabel(app.DTab_2);
            app.Label_3.Text = '範例';
            app.ListBox_2 = uilistbox(app.DTab_2);
            app.ListBox_2.Items = {'F1', 'F2'};
            app.ListBox_2.Value = 'F1';
            app.yEditField_2Label = uilabel(app.DTab_2);
            app.yEditField_2Label.Text = 'y';
            app.yEditField_2 = uieditfield(app.DTab_2, 'text');
            app.ColorKnobLabel = uilabel(app.DTab_2);
            app.ColorKnobLabel.Text = 'Color';
            app.ColorKnob = uiknob(app.DTab_2, 'discrete');
            app.ColorKnob.Items = {'Red', 'Green', 'Blue', 'Block'};
            app.ColorKnob.Value = 'Red';
            app.Button_22 = uibutton(app.DTab_2, 'push');
            app.Button_22.ButtonPushedFcn = createCallbackFcn(app, @Button_22Pushed, true);
            app.Button_22.Text = '生成範例';
            app.SpecialTab = uitab(app.TabGroup);
            app.SpecialTab.Title = 'Special';
            app.UIAxes2 = uiaxes(app.SpecialTab);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            colormap(app.UIAxes2, 'white')
            app.TabGroup3 = uitabgroup(app.SpecialTab);
            app.BarTab = uitab(app.TabGroup3);
            app.BarTab.Title = 'Bar';
            app.x_startEditField_2Label = uilabel(app.BarTab);
            app.x_startEditField_2Label.Text = 'x_start';
            app.x_startEditField_2 = uieditfield(app.BarTab, 'text');
            app.x_startEditField_2.Value = '0.5';
            app.x_endEditField_2Label = uilabel(app.BarTab);
            app.x_endEditField_2Label.Text = 'x_end';
            app.x_endEditField_2 = uieditfield(app.BarTab, 'text');
            app.x_endEditField_2.Value = '1.5';
            app.increaseEditField_2Label = uilabel(app.BarTab);
            app.increaseEditField_2Label.Text = 'increase';
            app.increaseEditField_2 = uieditfield(app.BarTab, 'text');
            app.increaseEditField_2.Value = '0.01';
            app.fxEditField_2Label = uilabel(app.BarTab);
            app.fxEditField_2Label.Text = 'f(x)';
            app.fxEditField_2 = uieditfield(app.BarTab, 'text');
            app.fxEditField_2.Value = 'sin(x)';
            app.Image = uiimage(app.BarTab);
            app.Image.ImageSource = fullfile(pathToMLAPP, '20211116_091152.jpg');
            app.PieTab = uitab(app.TabGroup3);
            app.PieTab.Title = 'Pie';
            app.xEditFieldLabel = uilabel(app.PieTab);
            app.xEditFieldLabel.Text = 'x';
            app.xEditField = uieditfield(app.PieTab, 'text');
            app.xEditField.Value = '6 8 8 19 9';
            app.labelEditFieldLabel = uilabel(app.PieTab);
            app.labelEditFieldLabel.Text = 'label';
            app.labelEditField = uieditfield(app.PieTab, 'text');
            app.labelEditField.Value = '''90 以上'',''80~89'',''70~79'',''60~69'',''60 分以下''';
            app.impEditFieldLabel = uilabel(app.PieTab);
            app.impEditFieldLabel.Text = 'imp';
            app.impEditField = uieditfield(app.PieTab, 'text');
            app.impEditField.Value = '0 0 0 1 1';
            app.PolarTab = uitab(app.TabGroup3);
            app.PolarTab.Title = 'Polar';
            app.s_startEditFieldLabel = uilabel(app.PolarTab);
            app.s_startEditFieldLabel.Text = 's_start';
            app.s_startEditField = uieditfield(app.PolarTab, 'text');
            app.s_startEditField.Value = '-pi';
            app.s_endEditFieldLabel = uilabel(app.PolarTab);
            app.s_endEditFieldLabel.Text = 's_end';
            app.s_endEditField = uieditfield(app.PolarTab, 'text');
            app.s_endEditField.Value = 'pi';
            app.increaseEditField_3Label = uilabel(app.PolarTab);
            app.increaseEditField_3Label.Text = 'increase';
            app.increaseEditField_3 = uieditfield(app.PolarTab, 'text');
            app.increaseEditField_3.Value = '0.01';
            app.rEditFieldLabel = uilabel(app.PolarTab);
            app.rEditFieldLabel.Text = 'r';
            app.rEditField = uieditfield(app.PolarTab, 'text');
            app.rEditField.Value = '3*(sin(5.*s)).^2';
            app.Image2 = uiimage(app.PolarTab);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'bomb2.png');
            app.StemTab = uitab(app.TabGroup3);
            app.StemTab.Title = 'Stem';
            app.LineStyleKnobLabel = uilabel(app.StemTab);
            app.LineStyleKnobLabel.Text = 'LineStyle';
            app.LineStyleKnob = uiknob(app.StemTab, 'discrete');
            app.LineStyleKnob.Items = {'Off', '實線', '虛線', '點線', '點虛線'};
            app.MarkerFaceColorKnobLabel = uilabel(app.StemTab);
            app.MarkerFaceColorKnobLabel.Text = 'MarkerFaceColor';
            app.MarkerFaceColorKnob = uiknob(app.StemTab, 'discrete');
            app.MarkerFaceColorKnob.Items = {'black', 'red', 'blue', 'green'};
            app.MarkerFaceColorKnob.Value = 'black';
            app.MarkerEdgeColorKnobLabel = uilabel(app.StemTab);
            app.MarkerEdgeColorKnobLabel.Text = 'MarkerEdgeColor';
            app.MarkerEdgeColorKnob = uiknob(app.StemTab, 'discrete');
            app.MarkerEdgeColorKnob.Items = {'black', 'red', 'blue', 'green'};
            app.MarkerEdgeColorKnob.Value = 'black';
            app.x_startEditField_3Label = uilabel(app.StemTab);
            app.x_startEditField_3Label.Text = 'x_start';
            app.x_startEditField_3 = uieditfield(app.StemTab, 'text');
            app.x_startEditField_3.Value = '0';
            app.increaseEditField_4Label = uilabel(app.StemTab);
            app.increaseEditField_4Label.Text = 'increase';
            app.increaseEditField_4 = uieditfield(app.StemTab, 'text');
            app.increaseEditField_4.Value = '0.1*pi';
            app.x_endEditField_3Label = uilabel(app.StemTab);
            app.x_endEditField_3Label.Text = 'x_end';
            app.x_endEditField_3 = uieditfield(app.StemTab, 'text');
            app.x_endEditField_3.Value = '2*pi';
            app.fxEditField_3Label = uilabel(app.StemTab);
            app.fxEditField_3Label.Text = 'f(x)';
            app.fxEditField_3 = uieditfield(app.StemTab, 'text');
            app.fxEditField_3.Value = '3./exp(x.^2)';
            app.ErrorbarTab = uitab(app.TabGroup3);
            app.ErrorbarTab.Title = 'Errorbar';
            app.x_startEditField_4Label = uilabel(app.ErrorbarTab);
            app.x_startEditField_4Label.Text = 'x_start';
            app.x_startEditField_4 = uieditfield(app.ErrorbarTab, 'text');
            app.x_startEditField_4.Value = '0';
            app.x_endEditField_4Label = uilabel(app.ErrorbarTab);
            app.x_endEditField_4Label.Text = 'x_end';
            app.x_endEditField_4 = uieditfield(app.ErrorbarTab, 'text');
            app.x_endEditField_4.Value = '2*pi';
            app.increaseEditField_5Label = uilabel(app.ErrorbarTab);
            app.increaseEditField_5Label.Text = 'increase';
            app.increaseEditField_5 = uieditfield(app.ErrorbarTab, 'text');
            app.increaseEditField_5.Value = '0.04*pi';
            app.yEditField_3Label = uilabel(app.ErrorbarTab);
            app.yEditField_3Label.Text = 'y';
            app.yEditField_3 = uieditfield(app.ErrorbarTab, 'text');
            app.yEditField_3.Value = '3.*sin(2.*x)';
            app.Label_5 = uilabel(app.ErrorbarTab);
            app.Label_5.Text = '誤差值';
            app.EditField_3 = uieditfield(app.ErrorbarTab, 'text');
            app.EditField_3.Value = 'y.*0.1';
            app.outputButton = uibutton(app.SpecialTab, 'push');
            app.outputButton.ButtonPushedFcn = createCallbackFcn(app, @outputButtonPushed, true);
            app.outputButton.Text = 'output';
            app.Tab_3 = uitab(app.TabGroup);
            app.Tab_3.Title = '計算機_2';
            app.GridLayout = uigridlayout(app.Tab_3);
            app.Button_42 = uibutton(app.GridLayout, 'push');
            app.Button_42.ButtonPushedFcn = createCallbackFcn(app, @Button_42Pushed, true);
            app.Button_42.Text = '4';
            app.CButton_2 = uibutton(app.GridLayout, 'push');
            app.CButton_2.ButtonPushedFcn = createCallbackFcn(app, @CButton_2Pushed, true);
            app.CButton_2.Text = 'C';
            app.Button_35 = uibutton(app.GridLayout, 'push');
            app.Button_35.ButtonPushedFcn = createCallbackFcn(app, @Button_35Pushed, true);
            app.Button_35.Text = '7';
            app.EditField_2 = uieditfield(app.GridLayout, 'text');
            app.Button_23 = uibutton(app.GridLayout, 'push');
            app.Button_23.ButtonPushedFcn = createCallbackFcn(app, @Button_23Pushed, true);
            app.Button_23.Text = '(';
            app.Button_43 = uibutton(app.GridLayout, 'push');
            app.Button_43.ButtonPushedFcn = createCallbackFcn(app, @Button_43Pushed, true);
            app.Button_43.Text = '5';
            app.Button_36 = uibutton(app.GridLayout, 'push');
            app.Button_36.ButtonPushedFcn = createCallbackFcn(app, @Button_36Pushed, true);
            app.Button_36.Text = '8';
            app.Button_24 = uibutton(app.GridLayout, 'push');
            app.Button_24.ButtonPushedFcn = createCallbackFcn(app, @Button_24Pushed, true);
            app.Button_24.Text = ')';
            app.Button_37 = uibutton(app.GridLayout, 'push');
            app.Button_37.ButtonPushedFcn = createCallbackFcn(app, @Button_37Pushed, true);
            app.Button_37.Text = '9';
            app.Button_44 = uibutton(app.GridLayout, 'push');
            app.Button_44.ButtonPushedFcn = createCallbackFcn(app, @Button_44Pushed, true);
            app.Button_44.Text = '6';
            app.Button_40 = uibutton(app.GridLayout, 'push');
            app.Button_40.ButtonPushedFcn = createCallbackFcn(app, @Button_40Pushed, true);
            app.Button_40.Text = '2';
            app.Button_25 = uibutton(app.GridLayout, 'push');
            app.Button_25.ButtonPushedFcn = createCallbackFcn(app, @Button_25Pushed, true);
            app.Button_25.Text = '%';
            app.Button_26 = uibutton(app.GridLayout, 'push');
            app.Button_26.ButtonPushedFcn = createCallbackFcn(app, @Button_26Pushed, true);
            app.Button_26.Text = '÷';
            app.XButton = uibutton(app.GridLayout, 'push');
            app.XButton.ButtonPushedFcn = createCallbackFcn(app, @XButtonPushed, true);
            app.XButton.Text = 'X';
            app.Button_27 = uibutton(app.GridLayout, 'push');
            app.Button_27.ButtonPushedFcn = createCallbackFcn(app, @Button_27Pushed, true);
            app.Button_27.Text = '-';
            app.Button_28 = uibutton(app.GridLayout, 'push');
            app.Button_28.ButtonPushedFcn = createCallbackFcn(app, @Button_28Pushed, true);
            app.Button_28.Text = '+';
            app.Button_39 = uibutton(app.GridLayout, 'push');
            app.Button_39.ButtonPushedFcn = createCallbackFcn(app, @Button_39Pushed, true);
            app.Button_39.Text = '1';
            app.Button_41 = uibutton(app.GridLayout, 'push');
            app.Button_41.ButtonPushedFcn = createCallbackFcn(app, @Button_41Pushed, true);
            app.Button_41.Text = '3';
            app.Button_38 = uibutton(app.GridLayout, 'push');
            app.Button_38.ButtonPushedFcn = createCallbackFcn(app, @Button_38Pushed, true);
            app.Button_38.Text = '0';
            app.Button_29 = uibutton(app.GridLayout, 'push');
            app.Button_29.ButtonPushedFcn = createCallbackFcn(app, @Button_29Pushed, true);
            app.Button_29.Text = '=';
            app.Button_30 = uibutton(app.GridLayout, 'push');
            app.Button_30.ButtonPushedFcn = createCallbackFcn(app, @Button_30Pushed, true);
            app.Button_30.Text = '[';
            app.Button_31 = uibutton(app.GridLayout, 'push');
            app.Button_31.ButtonPushedFcn = createCallbackFcn(app, @Button_31Pushed, true);
            app.Button_31.Text = ']';
            app.Button_33 = uibutton(app.GridLayout, 'push');
            app.Button_33.ButtonPushedFcn = createCallbackFcn(app, @Button_33Pushed, true);
            app.Button_33.Text = ',';
            app.Button_32 = uibutton(app.GridLayout, 'push');
            app.Button_32.ButtonPushedFcn = createCallbackFcn(app, @Button_32Pushed, true);
            app.Button_32.Text = ';';
            app.Button_34 = uibutton(app.GridLayout, 'push');
            app.Button_34.ButtonPushedFcn = createCallbackFcn(app, @Button_34Pushed, true);
            app.Button_34.Text = '.';
            app.sinButton = uibutton(app.GridLayout, 'push');
            app.sinButton.ButtonPushedFcn = createCallbackFcn(app, @sinButtonPushed, true);
            app.sinButton.Text = 'sin';
            app.cosButton = uibutton(app.GridLayout, 'push');
            app.cosButton.ButtonPushedFcn = createCallbackFcn(app, @cosButtonPushed, true);
            app.cosButton.Text = 'cos';
            app.tanButton = uibutton(app.GridLayout, 'push');
            app.tanButton.ButtonPushedFcn = createCallbackFcn(app, @tanButtonPushed, true);
            app.tanButton.Text = 'tan';
            app.eButton = uibutton(app.GridLayout, 'push');
            app.eButton.ButtonPushedFcn = createCallbackFcn(app, @eButtonPushed, true);
            app.eButton.Text = 'e';
            app.powButton = uibutton(app.GridLayout, 'push');
            app.powButton.ButtonPushedFcn = createCallbackFcn(app, @powButtonPushed, true);
            app.powButton.Text = 'pow';
            app.sqrtButton = uibutton(app.GridLayout, 'push');
            app.sqrtButton.ButtonPushedFcn = createCallbackFcn(app, @sqrtButtonPushed, true);
            app.sqrtButton.Text = 'sqrt';
            app.Button_45 = uibutton(app.GridLayout, 'push');
            app.Button_45.ButtonPushedFcn = createCallbackFcn(app, @Button_45Pushed, true);
            app.Button_45.Text = '⇚';
            app.lnButton = uibutton(app.GridLayout, 'push');
            app.lnButton.ButtonPushedFcn = createCallbackFcn(app, @lnButtonPushed, true);
            app.lnButton.Text = 'ln';
            app.log10Button = uibutton(app.GridLayout, 'push');
            app.log10Button.ButtonPushedFcn = createCallbackFcn(app, @log10ButtonPushed, true);
            app.log10Button.Text = 'log10';
            app.log2Button = uibutton(app.GridLayout, 'push');
            app.log2Button.ButtonPushedFcn = createCallbackFcn(app, @log2ButtonPushed, true);
            app.log2Button.Text = 'log2';
            app.EditField_4 = uieditfield(app.GridLayout, 'text');
            app.Button_46 = uibutton(app.GridLayout, 'push');
            app.Button_46.ButtonPushedFcn = createCallbackFcn(app, @Button_46Pushed, true);
            app.Button_46.Text = 'π';
            app.EditField_5 = uieditfield(app.GridLayout, 'text');
            app.Label_6 = uilabel(app.GridLayout);
            app.Label_6.Text = '提示:';
            app.Tab_4 = uitab(app.TabGroup);
            app.Tab_4.SizeChangedFcn = createCallbackFcn(app, @Tab_4SizeChanged, true);
            app.Tab_4.Title = '退出';
            app.Tab_4.ButtonDownFcn = createCallbackFcn(app, @Tab_4ButtonDown, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end